classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        TabGroup                   matlab.ui.container.TabGroup
        MainTab                    matlab.ui.container.Tab
        pnlEnroll                  matlab.ui.container.Panel
        tblEnrollQueue             matlab.ui.control.Table
        btnEnrollBack              matlab.ui.control.Button
        btnEnrollSave              matlab.ui.control.Button
        btnEnrollLoad              matlab.ui.control.Button
        axEnrollPreview            matlab.ui.control.UIAxes
        pnlHome                    matlab.ui.container.Panel
        GridLayout                 matlab.ui.container.GridLayout
        btnMenuMass                matlab.ui.control.Button
        btnMenuEnroll              matlab.ui.control.Button
        btnMenuIdentify            matlab.ui.control.Button
        Label_2                    matlab.ui.control.Label
        pnlMass                    matlab.ui.container.Panel
        gaugeProgress              matlab.ui.control.LinearGauge
        btnMassBack                matlab.ui.control.Button
        tblMassResults             matlab.ui.control.Table
        lblMassStatus              matlab.ui.control.Label
        pnlIdentify                matlab.ui.container.Panel
        Grid                       matlab.ui.container.GridLayout
        lblStatus                  matlab.ui.control.Label
        lampMatch                  matlab.ui.control.Lamp
        btnBackToHome              matlab.ui.control.Button
        axStep3                    matlab.ui.control.UIAxes
        axStep4                    matlab.ui.control.UIAxes
        axStep5                    matlab.ui.control.UIAxes
        axStep6                    matlab.ui.control.UIAxes
        axStep2                    matlab.ui.control.UIAxes
        axStep1                    matlab.ui.control.UIAxes
        SettingsTab                matlab.ui.container.Tab
        btnSaveSettings            matlab.ui.control.Button
        btnResetDefaults           matlab.ui.control.Button
        btnLabRun                  matlab.ui.control.Button
        btnLabLoadImg              matlab.ui.control.Button
        spnMinPoints               matlab.ui.control.Spinner
        MinPointsLabel             matlab.ui.control.Label
        spnMatchThresh             matlab.ui.control.Spinner
        MatchScoreSpinnerLabel     matlab.ui.control.Label
        spnBorder                  matlab.ui.control.Spinner
        BorderMarginSpinnerLabel   matlab.ui.control.Label
        sldBinarize                matlab.ui.control.Slider
        BinarizeSensSliderLabel    matlab.ui.control.Label
        sldMaskDilate              matlab.ui.control.Slider
        MaskDilationSliderLabel    matlab.ui.control.Label
        sldGaborThresh             matlab.ui.control.Slider
        GaborThresholdSliderLabel  matlab.ui.control.Label
        axLabPreview               matlab.ui.control.UIAxes
        DatabaseTab                matlab.ui.container.Tab
        btnRefreshDB               matlab.ui.control.Button
        btnDeleteUser              matlab.ui.control.Button
        tblDatabase                matlab.ui.control.Table
        lblDBInfo                  matlab.ui.control.Label
        axDBPreview                matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
           FingerprintDB       % מאגר הנתונים
    CurrentConfig       % הגדרות
    CurrentImage        % תמונה נוכחית
    CurrentMinutiae     % נקודות
    CurrentDescriptors  % מאפיינים
    end
    
    properties (Access = private)
        TempEnrollData % Description

        end
    % שנה את השורה הראשונה ל-public
    
    methods (Access = private)
        function updateDBTable(app)
            % בדיקה אם המאגר ריק
            if isempty(app.FingerprintDB)
                app.tblDatabase.Data = {};
                app.lblDBInfo.Text = 'המאגר ריק';
                return;
            end
            
            data = app.FingerprintDB;
            count = length(data);
            
            % יצירת טבלה להצגה
            displayData = cell(count, 3);
            for i = 1:count
                displayData{i, 1} = i;                  
                displayData{i, 2} = data(i).name;       
                
                % הגנה למקרה שאין Template
                if isfield(data(i), 'template') && ~isempty(data(i).template)
                    displayData{i, 3} = size(data(i).template, 1); 
                else
                    displayData{i, 3} = 0;
                end
            end
            
            % עדכון המסך
            app.tblDatabase.Data = displayData;
            app.lblDBInfo.Text = sprintf('סה"כ רשומים: %d', count);
        end
    end
    
    methods (Access = public)
        
        function syncSlidersWithConfig(app)
            cfg = app.CurrentConfig;
    
    if isprop(app, 'sldGaborThresh'), app.sldGaborThresh.Value = cfg.gabor.thresh; end
    if isprop(app, 'sldMaskDilate'), app.sldMaskDilate.Value = cfg.roi.mask_dilation; end
    if isprop(app, 'spnMinPoints'), app.spnMinPoints.Value = cfg.enroll.min_minutiae; end
    if isprop(app, 'spnMatchThresh'), app.spnMatchThresh.Value = cfg.match.pass_threshold; end
    if isprop(app, 'sldBinarize') && isfield(cfg, 'binarize'), app.sldBinarize.Value = cfg.binarize.sens; end
    if isprop(app, 'spnBorder') && isfield(cfg, 'filter'), app.spnBorder.Value = cfg.filter.border_margin; end
            
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
      % 1. הגדרת נתיבים (קריטי!)
            try
                setup_project(); 
            catch
                disp('Warning: setup_project failed or not found.');
            end
            
            % 2. טעינת הגדרות ומאגר
            try
                app.CurrentConfig = get_config();
                
                % --- חדש: סנכרון הסליידרים למצב הטעון ---
                % (כך שאם שמרת הגדרות אישיות, הסליידרים יזוזו למקום הנכון)
                syncSlidersWithConfig(app);
                % ----------------------------------------
                
            catch
                % הגדרות חירום אם נכשל
                app.CurrentConfig.db_filename = 'fingerprint_database.mat';
                app.CurrentConfig.enroll.min_minutiae = 12;
            end
            
            % 3. טעינת מסד הנתונים
            dbPath = app.CurrentConfig.db_filename;
            if isfile(dbPath)
                try
                    data = load(dbPath, 'fingerprintDB');
                    if isfield(data, 'fingerprintDB')
                        app.FingerprintDB = data.fingerprintDB;
                    else
                         app.FingerprintDB = [];
                    end
                catch
                    app.FingerprintDB = [];
                end
            else
                app.FingerprintDB = [];
            end
            
            % --- חדש: עדכון טבלת ה-Database מיד בהתחלה ---
            updateDBTable(app);
            % ---------------------------------------------
            
            % 4. איפוס תצוגה (חזרה למסך הבית)
            app.pnlHome.Visible = 'on';
            app.pnlIdentify.Visible = 'off';
            app.pnlEnroll.Visible = 'off';
            app.pnlMass.Visible = 'off';
            
            % איפוס מנורות ונראות נוספת אם צריך
            if isprop(app, 'lampMatch'), app.lampMatch.Color = [0.9 0.9 0.9]; end
        end

        % Button pushed function: btnMenuIdentify
        function btnMenuIdentifyButtonPushed(app, event)
            % 1. שלב ראשון: בחירת קובץ בודד
    dataDir = 'data/';
    if ~exist(dataDir, 'dir'), dataDir = pwd; end
    
    [file, path] = uigetfile({'*.tif;*.png;*.jpg', 'Fingerprint Images'}, 'בחר אצבע לזיהוי', dataDir);
    if isequal(file, 0), return; end % ביטול
    
    % 2. איפוס תצוגה ומעבר למסך הנכון
    % --- התיקון הקריטי כאן: מכבים את כל האחרים ומדליקים רק את Identify ---
    app.pnlHome.Visible = 'off';
    app.pnlMass.Visible = 'off';    % וודא שזה כבוי!
    app.pnlEnroll.Visible = 'off';  % וודא שזה כבוי!
    app.pnlIdentify.Visible = 'on'; % רק זה דולק!
    drawnow;
    
    % איפוס מחוונים ויזואליים
    app.lblStatus.Text = 'מעבד תמונה...';
    app.lblStatus.FontColor = [0 0 1]; % כחול
    app.lampMatch.Color = [0.9 0.9 0.9]; % אפור (כבוי)
    
    try
        % 3. טעינה ועיבוד (6 התחנות)
        fullPath = fullfile(path, file);
        app.CurrentImage = imread(fullPath);
        
        % קריאה לפונקציה הגרפית שמציירת את השלבים
        [template, ~, ~, descriptors] = process_fingerprint_GUI(app.CurrentImage, app);
        
        % 4. בדיקה אם המאגר ריק
        if isempty(app.FingerprintDB)
            app.lblStatus.Text = 'המאגר ריק (תצוגה בלבד)';
            uialert(app.UIFigure, 'אין משתמשים במאגר. לא ניתן לבצע זיהוי.', 'מידע');
            return;
        end
        
        % 5. ביצוע הזיהוי (Matching)
        app.lblStatus.Text = 'מחפש במאגר...';
        drawnow;
        
        bestScore = 0;
        bestName = 'Unknown';
        
        % הכנת הנתונים להשוואה
        inputData.minutiae = template;
        inputData.descriptors = descriptors;
        
        % לולאה על המאגר
        for i = 1:length(app.FingerprintDB)
            dbData.minutiae = app.FingerprintDB(i).template;
            dbData.descriptors = app.FingerprintDB(i).descriptors;
            
            if isfield(app.FingerprintDB(i), 'T_sq')
                dbData.T_sq = app.FingerprintDB(i).T_sq;
            end
            
            [score, ~] = find_best_match(dbData, inputData);
            
            if score > bestScore
                bestScore = score;
                bestName = app.FingerprintDB(i).name;
            end
        end
        
        % 6. הצגת התוצאה
        threshold = 15;
        if isfield(app.CurrentConfig.match, 'pass_threshold')
            threshold = app.CurrentConfig.match.pass_threshold;
        end
        
        if bestScore >= threshold
            % === זיהוי מוצלח ===
            app.lblStatus.Text = ['זוהה: ' bestName];
            app.lblStatus.FontColor = [0 0.6 0]; % ירוק
            app.lampMatch.Color = [0 1 0];       % מנורה ירוקה
        else
            % === אין זיהוי ===
            app.lblStatus.Text = 'לא נמצאה התאמה';
            app.lblStatus.FontColor = [0.8 0 0]; % אדום
            app.lampMatch.Color = [1 0 0];       % מנורה אדומה
        end
        
    catch err
        app.lblStatus.Text = 'שגיאה';
        uialert(app.UIFigure, ['שגיאה בתהליך: ' err.message], 'Error');
    end
        end

        % Button pushed function: btnBackToHome
        function btnBackToHomeButtonPushed(app, event)
            % חזרה לבית
    app.pnlIdentify.Visible = 'off';
    app.pnlHome.Visible = 'on';
        end

        % Button pushed function: btnMenuMass
        function btnMenuMassButtonPushed(app, event)
  % 1. הגדרת סוגי קבצים נתמכים
    fileTypes = {'*.tif;*.png;*.jpg;*.bmp', 'Image Files (*.tif, *.png, *.jpg)'; ...
                 '*.*', 'All Files (*.*)'};
    
    dataDir = 'data/';
    if ~exist(dataDir, 'dir'), dataDir = pwd; end
    
    % 2. בחירת קבצים מרובים (MultiSelect = on)
    [files, path] = uigetfile(fileTypes, 'בחר קבצים לזיהוי', dataDir, 'MultiSelect', 'on');
    
    % בדיקת ביטול
    if isequal(files, 0)
        return; 
    end
    
    % נרמול הקלט: אם נבחר קובץ אחד, להפוך אותו לתא (Cell) כדי שהקוד יעבוד תמיד
    if ischar(files)
        files = {files}; 
    end
    
    % בדיקה שהמאגר לא ריק
    if isempty(app.FingerprintDB)
        uialert(app.UIFigure, 'המאגר ריק. נא לבצע רישום משתמשים קודם.', 'שגיאה');
        return;
    end

    % 3. מעבר למסך התוצאות
    app.pnlHome.Visible = 'off';
    app.pnlIdentify.Visible = 'off';
    app.pnlMass.Visible = 'on'; 
    drawnow; 

    % 4. קריאה לפונקציה החיצונית
    % אנו שולחים לה את רשימת הקבצים (files) ואת הנתיב (path)
    try
        run_mass_identification(app, files, path);
    catch err
        uialert(app.UIFigure, ['שגיאה בהרצת הזיהוי: ' err.message], 'Error');
    end
        end

        % Button pushed function: btnMassBack
        function btnMassBackButtonPushed(app, event)
            % הסתרת מסך המוני
    app.pnlMass.Visible = 'off';
    
    % חזרה לבית
    app.pnlHome.Visible = 'on';
        end

        % Button pushed function: btnMenuEnroll
        function btnMenuEnrollButtonPushed(app, event)
            % מעבר למסך ההרשמה
    app.pnlHome.Visible = 'off';
    app.pnlEnroll.Visible = 'on';
    
    % --- איפוס המסך (מותאם לטבלה החדשה) ---
    app.TempEnrollData = [];       % ניקוי זיכרון
    app.tblEnrollQueue.Data = {};  % ניקוי הטבלה
    cla(app.axEnrollPreview);      % ניקוי התמונה
    
    % איפוס כותרת התמונה
    title(app.axEnrollPreview, 'תצוגה מקדימה');
        end

        % Button pushed function: btnEnrollLoad
        function btnEnrollLoadButtonPushed(app, event)
            % 1. בחירת קבצים
    [files, path] = uigetfile({'*.tif;*.png;*.jpg', 'Images'}, ...
                              'בחר קבצים להרשמה', 'data/', 'MultiSelect', 'on');
    if isequal(files, 0), return; end
    if ischar(files), files = {files}; end 
    
    totalFiles = length(files);
    
    % הגדרת כותרות הטבלה (מימין לשמאל לעברית)
    app.tblEnrollQueue.ColumnName = {'סטטוס', 'נקודות', 'שם מזוהה', 'שם קובץ'};
    
    % איפוסים
    app.TempEnrollData = []; 
    app.tblEnrollQueue.Data = {};
    
    tableDisplayData = {};
    tempList = []; 
    
    % 2. לולאת עיבוד
    for k = 1:totalFiles
        filename = files{k};
        fullPath = fullfile(path, filename);
        [~, nameNoExt, ~] = fileparts(filename);
        
        % עדכון סטטוס בכותרת התמונה
        title(app.axEnrollPreview, sprintf('מעבד %d/%d...', k, totalFiles));
        drawnow limitrate;
        
        try
            [data, img, err] = load_and_process_image(fullPath, app.CurrentConfig.enroll.min_minutiae);
            
            if ~isempty(err)
                status = '❌ נכשל';
                ptsCount = 0;
            else
                status = '✅ תקין';
                ptsCount = size(data.minutiae, 1);
                
                newItem.name = nameNoExt;
                newItem.minutiae = data.minutiae;
                newItem.descriptors = data.descriptors;
                newItem.imagePath = fullPath;
                newItem.processedImg = img;
                
                if isempty(tempList), tempList = newItem; else, tempList(end+1) = newItem; end
            end
        catch
            status = '⚠️ שגיאה';
            ptsCount = 0;
        end
        
        % עדכון הטבלה (סדר הפוך לעברית)
        tableDisplayData(end+1, :) = {status, ptsCount, nameNoExt, filename};
    end
    
    % 3. סיום
    app.tblEnrollQueue.Data = tableDisplayData;
    app.TempEnrollData = tempList; 
    
    title(app.axEnrollPreview, 'בחר שורה בטבלה להצגה');
    
    if ~isempty(tempList)
        imshow(tempList(1).processedImg, 'Parent', app.axEnrollPreview);
        hold(app.axEnrollPreview, 'on');
        plot(app.axEnrollPreview, tempList(1).minutiae(:,1), tempList(1).minutiae(:,2), 'r.');
        hold(app.axEnrollPreview, 'off');
    end
    
    % --- התיקון: הודעת הצלחה עם אייקון ירוק ---
    msg = sprintf('סריקה הושלמה: %d קבצים נבדקו.\n%d נמצאו תקינים לשמירה.', totalFiles, length(tempList));
    uialert(app.UIFigure, msg, 'הצלחה', 'Icon', 'success');
        end

        % Button pushed function: btnEnrollSave
        function btnEnrollSaveButtonPushed(app, event)
            % 1. בדיקה שיש נתונים מוכנים לשמירה
    if isempty(app.TempEnrollData)
        uialert(app.UIFigure, 'אין נתונים תקינים לשמירה. נא לטעון קבצים.', 'ריק', 'Icon', 'warning');
        return;
    end
    
    newUsersList = app.TempEnrollData;
    count = length(newUsersList);
    
    % 2. לולאת הוספה למאגר
    for i = 1:count
        newEntry = struct();
        newEntry.name = newUsersList(i).name;
        newEntry.template = newUsersList(i).minutiae;
        newEntry.descriptors = newUsersList(i).descriptors;
        newEntry.imagePath = newUsersList(i).imagePath;
        
        % --- כאן השינוי החשוב: שמירת התמונה החתוכה ---
        % זה מה שיגרום לנקודות לשבת בול בטאב Database
        newEntry.processedImg = newUsersList(i).processedImg; 
        
        % חישוב T_sq
        newEntry.T_sq = sum(newEntry.template(:,1:2).^2, 2);
        
        if isempty(app.FingerprintDB)
            app.FingerprintDB = newEntry;
        else
            app.FingerprintDB(end+1) = newEntry;
        end
    end
    
    % 3. שמירה לקובץ ויציאה
    try
        fingerprintDB = app.FingerprintDB;
        save(app.CurrentConfig.db_filename, 'fingerprintDB');
        
        % --- עדכון הטאב השני (Database) שיראה את השינוי מיד ---
        % (אם הפונקציה הזו קיימת אצלך, זה יעדכן את הרשימה)
        try
            updateDBTable(app); 
        catch
            % אם אין את הפונקציה, לא נורא
        end
        
        % הודעת הצלחה
        msg = sprintf('נוספו בהצלחה %d משתמשים למאגר!', count);
        uialert(app.UIFigure, msg, 'הצלחה', 'Icon', 'success');
        
        % ניקוי נתונים
        app.TempEnrollData = [];
        app.tblEnrollQueue.Data = {};
        cla(app.axEnrollPreview);
        title(app.axEnrollPreview, 'תצוגה מקדימה');
        
        % חזרה למסך הבית
        app.pnlEnroll.Visible = 'off';
        app.pnlHome.Visible = 'on';
        
    catch err
        uialert(app.UIFigure, ['שגיאה בשמירה: ' err.message], 'Error', 'Icon', 'error');
    end
        end

        % Button pushed function: btnEnrollBack
        function btnEnrollBackButtonPushed(app, event)
            app.pnlEnroll.Visible = 'off';
    app.pnlHome.Visible = 'on';
        end

        % Selection changed function: tblEnrollQueue
        function tblEnrollQueueSelectionChanged(app, event)
            % 1. זיהוי השורה שנבחרה
    indices = event.Selection; 
    if isempty(indices), return; end
    rowIdx = indices(1);
    
    % 2. שליפת שם הקובץ (מעמודה 4 - הימנית ביותר בעברית)
    if size(app.tblEnrollQueue.Data, 2) >= 4
        fileName = app.tblEnrollQueue.Data{rowIdx, 4}; 
    else
        return; % הגנה ממצב שהטבלה ריקה או לא בנויה טוב
    end
    
    % 3. חיפוש התמונה בזיכרון והצגתה
    found = false;
    if ~isempty(app.TempEnrollData)
        for i = 1:length(app.TempEnrollData)
            [~, fName, ~] = fileparts(app.TempEnrollData(i).imagePath);
            
            % השוואה חכמה לשם הקובץ
            if strcmp(fName, fileparts(fileName)) || strcmp([fName '.tif'], fileName) || strcmp(fName, fileName)
                data = app.TempEnrollData(i);
                
                % הצגה גרפית
                imshow(data.processedImg, 'Parent', app.axEnrollPreview);
                hold(app.axEnrollPreview, 'on');
                plot(app.axEnrollPreview, data.minutiae(:,1), data.minutiae(:,2), 'r.');
                hold(app.axEnrollPreview, 'off');
                title(app.axEnrollPreview, data.name);
                
                found = true;
                break;
            end
        end
    end
    
    % 4. טיפול במקרה שלא נמצאה תמונה
    if ~found
        cla(app.axEnrollPreview);
        title(app.axEnrollPreview, 'תמונה לא זמינה');
    end
        end

        % Button pushed function: btnRefreshDB
        function btnRefreshDBButtonPushed(app, event)
            % טעינה מחדש מהקובץ
    if isfile(app.CurrentConfig.db_filename)
        data = load(app.CurrentConfig.db_filename, 'fingerprintDB');
        if isfield(data, 'fingerprintDB')
            app.FingerprintDB = data.fingerprintDB;
        end
    end
    updateDBTable(app); % קריאה לפונקציית העזר
        end

        % Button pushed function: btnDeleteUser
        function btnDeleteUserButtonPushed(app, event)
            indices = app.tblDatabase.Selection;
    if isempty(indices)
        uialert(app.UIFigure, 'נא לבחור שורה למחיקה.', 'שגיאה');
        return;
    end
    
    rowIdx = indices(1);
    
    % הגנה מקריסה אם הטבלה לא מסונכרנת
    if rowIdx > length(app.FingerprintDB), return; end

    userName = app.FingerprintDB(rowIdx).name;
    
    % וידוא עם המשתמש
    selection = uiconfirm(app.UIFigure, ...
        ['האם אתה בטוח שברצונך למחוק את ' userName '?'], ...
        'אישור מחיקה', 'Icon', 'warning');
    
    if strcmp(selection, 'OK')
        % מחיקה מהזיכרון
        app.FingerprintDB(rowIdx) = [];
        
        % שמירה לקובץ
        fingerprintDB = app.FingerprintDB;
        save(app.CurrentConfig.db_filename, 'fingerprintDB');
        
        % עדכון תצוגה
        updateDBTable(app);
        cla(app.axDBPreview);
        
        uialert(app.UIFigure, 'המשתמש נמחק.', 'בוצע', 'Icon', 'success');
    end
        end

        % Selection changed function: tblDatabase
        function tblDatabaseSelectionChanged(app, event)
          indices = event.Selection;
            if isempty(indices), return; end
            
            rowIdx = indices(1);
            
            % הגנה מפני בחירה לא חוקית
            if rowIdx > length(app.FingerprintDB), return; end
            
            user = app.FingerprintDB(rowIdx);
            
            try
                % --- התיקון: בדיקה והצגה של התמונה הנכונה ---
                
                % אפשרות א': משתמש חדש (התמונה שמורה במאגר)
                if isfield(user, 'processedImg') && ~isempty(user.processedImg)
                    img = user.processedImg;
                    titleText = user.name;
                    
                % אפשרות ב': משתמש ישן (יש רק נתיב, והתצוגה תהיה לא מדויקת)
                elseif isfield(user, 'imagePath') && exist(user.imagePath, 'file')
                    img = imread(user.imagePath);
                    titleText = [user.name ' (משתמש ישן - ייתכן היסט)'];
                    
                % אפשרות ג': אין תמונה
                else
                    error('לא נמצאה תמונה למשתמש זה.');
                end
                
                % הצגת התמונה והנקודות
                imshow(img, 'Parent', app.axDBPreview);
                hold(app.axDBPreview, 'on');
                % ציור הנקודות באדום, גדול וברור
                plot(app.axDBPreview, user.template(:,1), user.template(:,2), 'r.', 'MarkerSize', 15);
                hold(app.axDBPreview, 'off');
                title(app.axDBPreview, titleText, 'Interpreter', 'none');
                
            catch err
                cla(app.axDBPreview);
                title(app.axDBPreview, 'תמונה לא זמינה');
                % אופציונלי: להציג את השגיאה בחלון הפקודות לדיבוג
                % disp(err.message); 
            end
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab;
            
            % בדיקה: האם עברנו ללשונית Database?
            if selectedTab == app.DatabaseTab
                
                % --- לוגיקה של רענון (במקום לקרוא לפונקציה חיצונית) ---
                
                % 1. טעינה מהקובץ
                if isfile(app.CurrentConfig.db_filename)
                    try
                        data = load(app.CurrentConfig.db_filename, 'fingerprintDB');
                        if isfield(data, 'fingerprintDB')
                            app.FingerprintDB = data.fingerprintDB;
                        end
                    catch
                        % אם הטעינה נכשלה, לא עושים כלום (משאירים מה שיש בזיכרון)
                    end
                end
                
                % 2. עדכון הטבלה (updateDBTable חייבת להיות קיימת כי השתמשנו בה קודם)
                % אם גם זה זורק שגיאה, תגיד לי ואתן לך את הקוד המלא שלה
                updateDBTable(app); 
                
                % 3. ניקוי התצוגה המקדימה
                cla(app.axDBPreview);
                title(app.axDBPreview, 'תצוגה מקדימה');
            end
        end

        % Button pushed function: btnLabLoadImg
        function btnLabLoadImgButtonPushed(app, event)
            [file, path] = uigetfile({'*.tif;*.png;*.jpg', 'Fingerprint'}, 'בחר תמונה', 'data/');
            if isequal(file, 0), return; end
            
            fullPath = fullfile(path, file);
            app.CurrentImage = imread(fullPath);
            
            % הצגה ראשונית
            imshow(app.CurrentImage, 'Parent', app.axLabPreview);
            title(app.axLabPreview, 'התמונה נטענה. שנה הגדרות ולחץ על "הרץ ניתוח"');
        end

        % Button pushed function: btnLabRun
        function btnLabRunButtonPushed(app, event)
            if isempty(app.CurrentImage)
                uialert(app.UIFigure, 'נא לטעון תמונה קודם.', 'שגיאה');
                return;
            end
            
            % 1. עדכון הקונפיגורציה מהממשק
            app.CurrentConfig.gabor.thresh = app.sldGaborThresh.Value;
            app.CurrentConfig.roi.mask_dilation = round(app.sldMaskDilate.Value);
            app.CurrentConfig.enroll.min_minutiae = app.spnMinPoints.Value;
            app.CurrentConfig.match.pass_threshold = app.spnMatchThresh.Value;
            
            % הגדרות נוספות
            if isprop(app, 'sldBinarize'), app.CurrentConfig.binarize.sens = app.sldBinarize.Value; end
            if isprop(app, 'spnBorder'), app.CurrentConfig.filter.border_margin = app.spnBorder.Value; end
            
            try
                title(app.axLabPreview, 'מעבד...'); drawnow;
                
                % 2. הרצת האלגוריתם - תופסים גם את המסיכה (roiMask)!
                % [template, roiMask, rawMinutiae, descriptors, processedImg]
                [template, roiMask, ~, ~, processedImg] = process_fingerprint_GUI(app.CurrentImage, app);
                
                % 3. הצגת התמונה
                imshow(processedImg, 'Parent', app.axLabPreview);
                hold(app.axLabPreview, 'on');
                
                % --- חדש: ציור גבולות המסיכה בצהוב ---
                % זה מראה בדיוק איפה האלגוריתם חותך
                visboundaries(app.axLabPreview, roiMask, 'Color', 'y', 'LineWidth', 2);
                % -------------------------------------
                
                numPoints = size(template, 1);
                minReq = app.CurrentConfig.enroll.min_minutiae;
                
                % צביעת הנקודות
                if numPoints >= minReq
                    plot(app.axLabPreview, template(:,1), template(:,2), 'g.', 'MarkerSize', 15);
                    statusColor = 'green';
                    statusText = '✅ עובר';
                else
                    plot(app.axLabPreview, template(:,1), template(:,2), 'r.', 'MarkerSize', 15);
                    statusColor = 'red';
                    statusText = '❌ נכשל';
                end
                hold(app.axLabPreview, 'off');
                
                title(app.axLabPreview, sprintf('נקודות: %d | %s (קו צהוב = מסיכה)', numPoints, statusText), 'Color', statusColor);
                
            catch err
                uialert(app.UIFigure, ['שגיאה: ' err.message], 'Error');
            end
        end

        % Button pushed function: btnResetDefaults
        function btnResetDefaultsButtonPushed(app, event)
            % 1. מחיקת קובץ ההגדרות האישיות (אם קיים)
            try
                configFuncPath = which('get_config');
                [configDir, ~, ~] = fileparts(configFuncPath);
                savePath = fullfile(configDir, 'user_settings.mat');
                
                if isfile(savePath)
                    delete(savePath); % מחיקת הקובץ
                end
                
                % 2. טעינה מחדש של הקונפיגורציה (עכשיו היא תהיה נקייה)
                app.CurrentConfig = get_config();
                
                % 3. סנכרון הסליידרים למצב החדש (פונקציית עזר למטה)
                syncSlidersWithConfig(app);
                
                uialert(app.UIFigure, 'ההגדרות שוחזרו לברירת המחדל המקורית.', 'שוחזר', 'Icon', 'success');
                
            catch err
                uialert(app.UIFigure, ['שגיאה בשחזור: ' err.message], 'Error');
            end
        end

        % Button pushed function: btnSaveSettings
        function btnSaveSettingsButtonPushed(app, event)
            % 1. איסוף הערכים מהמסך למבנה ההגדרות
            app.CurrentConfig.gabor.thresh = app.sldGaborThresh.Value;
            app.CurrentConfig.roi.mask_dilation = round(app.sldMaskDilate.Value);
            app.CurrentConfig.enroll.min_minutiae = app.spnMinPoints.Value;
            app.CurrentConfig.match.pass_threshold = app.spnMatchThresh.Value;
            
            if isprop(app, 'sldBinarize'), app.CurrentConfig.binarize.sens = app.sldBinarize.Value; end
            if isprop(app, 'spnBorder'), app.CurrentConfig.filter.border_margin = app.spnBorder.Value; end
            
            % 2. שמירה לקובץ
            try
                % מציאת המיקום של קובץ get_config כדי לשמור לידו
                configFuncPath = which('get_config');
                [configDir, ~, ~] = fileparts(configFuncPath);
                savePath = fullfile(configDir, 'user_settings.mat');
                
                % שמירה
                savedCfg = app.CurrentConfig;
                save(savePath, 'savedCfg');
                
                uialert(app.UIFigure, 'ההגדרות נשמרו בהצלחה.', 'נשמר', 'Icon', 'success');
            catch err
                uialert(app.UIFigure, ['שגיאה בשמירה: ' err.message], 'Error');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.MainTab = uitab(app.TabGroup);
            app.MainTab.Title = 'Main';
            app.pnlIdentify = uipanel(app.MainTab);
            app.Grid = uigridlayout(app.pnlIdentify);
            app.axStep1 = uiaxes(app.Grid);
            app.axStep1.XTick = [];
            app.axStep1.YTick = [];
            app.axStep1.ZTick = [];
            app.axStep2 = uiaxes(app.Grid);
            app.axStep2.XTick = [];
            app.axStep2.YTick = [];
            app.axStep2.ZTick = [];
            app.axStep6 = uiaxes(app.Grid);
            app.axStep6.XTick = [];
            app.axStep6.YTick = [];
            app.axStep6.ZTick = [];
            app.axStep5 = uiaxes(app.Grid);
            app.axStep5.XTick = [];
            app.axStep5.YTick = [];
            app.axStep5.ZTick = [];
            app.axStep4 = uiaxes(app.Grid);
            app.axStep4.XTick = [];
            app.axStep4.YTick = [];
            app.axStep4.ZTick = [];
            app.axStep3 = uiaxes(app.Grid);
            app.axStep3.XTick = [];
            app.axStep3.YTick = [];
            app.axStep3.ZTick = [];
            app.btnBackToHome = uibutton(app.Grid, 'push');
            app.btnBackToHome.ButtonPushedFcn = createCallbackFcn(app, @btnBackToHomeButtonPushed, true);
            app.btnBackToHome.Text = 'Back';
            app.lampMatch = uilamp(app.Grid);
            app.lblStatus = uilabel(app.Grid);
            app.lblStatus.Text = 'Ready';
            app.pnlMass = uipanel(app.MainTab);
            app.lblMassStatus = uilabel(app.pnlMass);
            app.lblMassStatus.Text = 'ממתין לנתונים...';
            app.tblMassResults = uitable(app.pnlMass);
            app.tblMassResults.ColumnName = {'קובץ'; 'אמת (ID)'; 'זוהה כ'; 'ציון'; 'סטטוס'};
            app.tblMassResults.RowName = {};
            app.btnMassBack = uibutton(app.pnlMass, 'push');
            app.btnMassBack.ButtonPushedFcn = createCallbackFcn(app, @btnMassBackButtonPushed, true);
            app.btnMassBack.Text = 'חזור';
            app.gaugeProgress = uigauge(app.pnlMass, 'linear');
            app.pnlHome = uipanel(app.MainTab);
            app.GridLayout = uigridlayout(app.pnlHome);
            app.Label_2 = uilabel(app.GridLayout);
            app.Label_2.Text = 'מערכת לזיהוי טביעות אצבע';
            app.btnMenuIdentify = uibutton(app.GridLayout, 'push');
            app.btnMenuIdentify.ButtonPushedFcn = createCallbackFcn(app, @btnMenuIdentifyButtonPushed, true);
            app.btnMenuIdentify.Text = 'זיהוי בודד (Single ID)';
            app.btnMenuEnroll = uibutton(app.GridLayout, 'push');
            app.btnMenuEnroll.ButtonPushedFcn = createCallbackFcn(app, @btnMenuEnrollButtonPushed, true);
            app.btnMenuEnroll.Text = 'הוספת משתמש (Enrollment)';
            app.btnMenuMass = uibutton(app.GridLayout, 'push');
            app.btnMenuMass.ButtonPushedFcn = createCallbackFcn(app, @btnMenuMassButtonPushed, true);
            app.btnMenuMass.Text = 'זיהוי המוני (Mass ID)';
            app.pnlEnroll = uipanel(app.MainTab);
            app.pnlEnroll.Title = 'Panel';
            app.axEnrollPreview = uiaxes(app.pnlEnroll);
            app.axEnrollPreview.XTick = [];
            app.axEnrollPreview.YTick = [];
            app.btnEnrollLoad = uibutton(app.pnlEnroll, 'push');
            app.btnEnrollLoad.ButtonPushedFcn = createCallbackFcn(app, @btnEnrollLoadButtonPushed, true);
            app.btnEnrollLoad.Text = 'טען תמונה';
            app.btnEnrollSave = uibutton(app.pnlEnroll, 'push');
            app.btnEnrollSave.ButtonPushedFcn = createCallbackFcn(app, @btnEnrollSaveButtonPushed, true);
            app.btnEnrollSave.Text = 'שמור למאגר';
            app.btnEnrollBack = uibutton(app.pnlEnroll, 'push');
            app.btnEnrollBack.ButtonPushedFcn = createCallbackFcn(app, @btnEnrollBackButtonPushed, true);
            app.btnEnrollBack.Text = 'חזור';
            app.tblEnrollQueue = uitable(app.pnlEnroll);
            app.tblEnrollQueue.ColumnName = {'סטטוס'; 'נקודות'; 'שם מזוהה'; 'קובץ'};
            app.tblEnrollQueue.RowName = {};
            app.tblEnrollQueue.SelectionChangedFcn = createCallbackFcn(app, @tblEnrollQueueSelectionChanged, true);
            app.SettingsTab = uitab(app.TabGroup);
            app.SettingsTab.Title = 'Settings';
            app.axLabPreview = uiaxes(app.SettingsTab);
            zlabel(app.axLabPreview, 'Z')
            app.GaborThresholdSliderLabel = uilabel(app.SettingsTab);
            app.GaborThresholdSliderLabel.Text = 'Gabor Threshold (רגישות)';
            app.sldGaborThresh = uislider(app.SettingsTab);
            app.sldGaborThresh.Limits = [0 0.5];
            app.sldGaborThresh.Value = 0.1;
            app.MaskDilationSliderLabel = uilabel(app.SettingsTab);
            app.MaskDilationSliderLabel.Text = 'Mask Dilation (הרחבה)';
            app.sldMaskDilate = uislider(app.SettingsTab);
            app.sldMaskDilate.Limits = [0 30];
            app.sldMaskDilate.Value = 20;
            app.BinarizeSensSliderLabel = uilabel(app.SettingsTab);
            app.BinarizeSensSliderLabel.Text = 'Binarize Sens (עובי קו)';
            app.sldBinarize = uislider(app.SettingsTab);
            app.sldBinarize.Limits = [0 1];
            app.sldBinarize.Value = 0.5;
            app.BorderMarginSpinnerLabel = uilabel(app.SettingsTab);
            app.BorderMarginSpinnerLabel.Text = 'Border Margin (שוליים)';
            app.spnBorder = uispinner(app.SettingsTab);
            app.spnBorder.Limits = [0 50];
            app.spnBorder.Value = 25;
            app.MatchScoreSpinnerLabel = uilabel(app.SettingsTab);
            app.MatchScoreSpinnerLabel.Text = 'Match Score (סף זיהוי)';
            app.spnMatchThresh = uispinner(app.SettingsTab);
            app.spnMatchThresh.Limits = [1 100];
            app.spnMatchThresh.Value = 12;
            app.MinPointsLabel = uilabel(app.SettingsTab);
            app.MinPointsLabel.Text = 'Min Points (''נקודות מינ)';
            app.spnMinPoints = uispinner(app.SettingsTab);
            app.spnMinPoints.Limits = [1 50];
            app.spnMinPoints.Value = 12;
            app.btnLabLoadImg = uibutton(app.SettingsTab, 'push');
            app.btnLabLoadImg.ButtonPushedFcn = createCallbackFcn(app, @btnLabLoadImgButtonPushed, true);
            app.btnLabLoadImg.Text = 'טען תמונה ';
            app.btnLabRun = uibutton(app.SettingsTab, 'push');
            app.btnLabRun.ButtonPushedFcn = createCallbackFcn(app, @btnLabRunButtonPushed, true);
            app.btnLabRun.Text = 'הרץ ניתוח';
            app.btnResetDefaults = uibutton(app.SettingsTab, 'push');
            app.btnResetDefaults.ButtonPushedFcn = createCallbackFcn(app, @btnResetDefaultsButtonPushed, true);
            app.btnResetDefaults.Text = 'שחזר ברירת מחדל';
            app.btnSaveSettings = uibutton(app.SettingsTab, 'push');
            app.btnSaveSettings.ButtonPushedFcn = createCallbackFcn(app, @btnSaveSettingsButtonPushed, true);
            app.btnSaveSettings.Text = 'שמירה';
            app.DatabaseTab = uitab(app.TabGroup);
            app.DatabaseTab.Title = 'Database';
            app.axDBPreview = uiaxes(app.DatabaseTab);
            title(app.axDBPreview, 'Title')
            zlabel(app.axDBPreview, 'Z')
            app.lblDBInfo = uilabel(app.DatabaseTab);
            app.lblDBInfo.Text = 'סה"כ רשומים: 0';
            app.tblDatabase = uitable(app.DatabaseTab);
            app.tblDatabase.ColumnName = {'ID'; 'Name'; 'Points'};
            app.tblDatabase.RowName = {};
            app.tblDatabase.SelectionChangedFcn = createCallbackFcn(app, @tblDatabaseSelectionChanged, true);
            app.btnDeleteUser = uibutton(app.DatabaseTab, 'push');
            app.btnDeleteUser.ButtonPushedFcn = createCallbackFcn(app, @btnDeleteUserButtonPushed, true);
            app.btnDeleteUser.Text = 'מחק משתמש';
            app.btnRefreshDB = uibutton(app.DatabaseTab, 'push');
            app.btnRefreshDB.ButtonPushedFcn = createCallbackFcn(app, @btnRefreshDBButtonPushed, true);
            app.btnRefreshDB.Text = 'רענון';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end